classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        roadSelection_DropDown        matlab.ui.control.DropDown
        ChoosetickrateLabel           matlab.ui.control.Label
        ChooseroadLabel               matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        selectTime_Slider             matlab.ui.control.Slider
        generateVisualization_Button  matlab.ui.control.Button
        date_DropDown                 matlab.ui.control.DropDown
        tickTime_DropDown             matlab.ui.control.DropDown
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function drawTriangle(app)
    % Clear the axes to start fresh
    cla(app.UIAxes);

    % Load the shapefile
    shp = shaperead('emeryville.shp');

    % Extract coordinates
    lon = [shp.X];
    lat = [shp.Y];

    % Create a map axes on the UIAxes
    axesm('mercator');  % Use an appropriate map projection
    hold on;

    % Plot the shapefile using geoshow
    %geoshow(app.UIAxes,lat, lon, 'DisplayType', 'line', 'Color', 'blue', 'LineWidth', 1);

    % Plot the shapefile on the app.UIAxes
    plot(app.UIAxes, lon, lat, '-b', 'LineWidth', 1);

    % Customize the map
    gridm on;
    tightmap;

    % Add title and labels if needed
    title(app.UIAxes, 'Emeryville Shapefile');
    xlabel(app.UIAxes, 'Longitude');
    ylabel(app.UIAxes, 'Latitude');

    hold off;
end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.date_DropDown.Enable = false;
            app.tickTime_DropDown.Enable = false;
            app.selectTime_Slider.Enable = false;
            app.generateVisualization_Button.Enable = false;
            %{
            % Define the URL
            link = 'https://data.transportation.gov/resource/8ect-6jqj.json?location=';
            
            url = [link,app.roadSelection_ListBox.Value];
            
            % Specify the app token if needed
            appToken = 'VNEI5hNy8NgCnI0lmzVtmlCE1';
            options = weboptions('KeyName', 'app_token', 'KeyValue', appToken);
            
            % Retrieve data from the URL
            data = webread(url, options);
            
            % Extract unique locations
            locations = unique({data.location});
            
            % Create a MATLAB app and populate the dropdown
            app.roadSelection_ListBox.Items = locations;
            %}
        end

        % Callback function: not associated with a component
        function roadSelection_ListBoxValueChanged2(app, event)
            
        end

        % Button pushed function: generateVisualization_Button
        function generateVisualization_ButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            tickString = app.tickTime_DropDown.Value;
            tickValue = str2double(tickString);
            
            if tickValue == 1
                app.selectTime_Slider.Enable = false;
                drawTriangle(app);
            

            else
            app.selectTime_Slider.Enable = true;
            

            app.selectTime_Slider.Limits = [900/tickValue, 900];
            disp([900/tickValue, 900]);
            
            quantizedValues = 900/tickValue:900/tickValue:900;
            quantizedValuesMajor = 900/15:900/15:900;
            app.selectTime_Slider.MinorTicks = quantizedValues;
            app.selectTime_Slider.MajorTicks = quantizedValuesMajor;
            
            
            app.selectTime_Slider.Value = quantizedValues(1);
            disp(quantizedValues(1));
            end


        end

        % Value changed function: selectTime_Slider
        function selectTime_SliderValueChanged(app, event)
           
           value = app.selectTime_Slider.Value;
           quantizedValues = app.selectTime_Slider.MinorTicks;
           roundedValue = round(value / (900/length(quantizedValues))) * (900/length(quantizedValues));
          
           app.selectTime_Slider.Value=roundedValue;
           disp(roundedValue);


        end

        % Value changed function: roadSelection_DropDown
        function roadSelection_DropDownValueChanged(app, event)
            value = app.roadSelection_DropDown.Value;
            app.date_DropDown.Enable = true;
            app.tickTime_DropDown.Enable = true;


            switch value

                case "us-101"
                    app.generateVisualization_Button.Enable = true;
                    Items = ["7:50 - 8:05", "8:05 - 8:20", "8:20 - 8:35"];
                    ItemsData = {
                        [1118846979700, 1118847879700],
                        [1118847879700, 1118848779700],
                        [1118848779700, 1118849679700]
                    };
                    app.date_DropDown.Items = Items;
                    app.date_DropDown.ItemsData = ItemsData;
            
                case "i-80"
                    app.generateVisualization_Button.Enable = true;
                    Items = ["16:00 - 16:15", "17:00 - 17:15", "17:15 - 17:30"];
                    ItemsData = {
                        [1113433135300, (1113433135300+900000)],
                        [(1113433135300+(4*900000)), (1113433135300+(5*900000))],
                        [(1113433135300+(5*900000)), (1113433135300+(6*900000))]
                    };
                    app.date_DropDown.Items = Items;
                    app.date_DropDown.ItemsData = ItemsData;
            
                case "lankershim"
                    app.generateVisualization_Button.Enable = true;
                    Items = ["8:30 - 8:45", "8:45 - 9:00"];
                    ItemsData = {
                        [1118935680200, (1118935680200+900000)],
                        [(1118935680200+(2*900000)), (1118935680200+(3*900000))]
                    };
                    app.date_DropDown.Items = Items;
                    app.date_DropDown.ItemsData = ItemsData;
            
                case "peachtree"
                    app.generateVisualization_Button.Enable = true;
                    Items = ["12:45 - 13:00", "16:00 - 16:15"];
                    ItemsData = {
                        [1163019100, 1163519100],
                        [1163519100, 1164063200]
                    };
                    app.date_DropDown.Items = Items;
                    app.date_DropDown.ItemsData = ItemsData;
            
                otherwise
                    app.generateVisualization_Button.Enable = false;
                    try
    disp(app.generateVisualization_Button.Enable);
                    catch ME
    disp(['Error: ' ME.message]);
                    end
                    app.date_DropDown.Enable = false;
                    app.tickTime_DropDown.Enable = false;
                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Traffic Visualization')
            zlabel(app.UIAxes, 'Z')
            app.tickTime_DropDown = uidropdown(app.UIFigure);
            app.tickTime_DropDown.Items = {'15 minutes', '60 seconds', '30 seconds', '10 seconds'};
            app.tickTime_DropDown.ItemsData = {'1', '15', '30', '90'};
            app.tickTime_DropDown.Value = '15';
            app.date_DropDown = uidropdown(app.UIFigure);
            app.date_DropDown.Items = {};
            app.date_DropDown.Placeholder = 'choose time';
            app.date_DropDown.Value = {};
            app.generateVisualization_Button = uibutton(app.UIFigure, 'push');
            app.generateVisualization_Button.ButtonPushedFcn = createCallbackFcn(app, @generateVisualization_ButtonPushed, true);
            app.generateVisualization_Button.Text = 'Generate Visualization';
            app.selectTime_Slider = uislider(app.UIFigure);
            app.selectTime_Slider.ValueChangedFcn = createCallbackFcn(app, @selectTime_SliderValueChanged, true);
            app.selectTime_Slider.MinorTicks = [0 1.2 2.4 3.6 4.8 6 7.2 8.4 9.6 10.8 12 13.2 14.4 15.6 16.8 18 19.2 20.4 21.6 22.8 24 25.2 26.4 27.6 28.8 30 31.2 32.4 33.6 34.8 36 37.2 38.4 39.6 40.8 42 43.2 44.4 45.6 46.8 48 49.2 50.4 51.6 52.8 54 55.2 56.4 57.6 58.8 60 61.2 62.4 63.6 64.8 66 67.2 68.4 69.6 70.8 72 73.2 74.4 75.6 76.8 78 79.2 80.4 81.6 82.8 84 85.2 86.4 87.6 88.8 90 91.2 92.4 93.6 94.8 96 97.2 98.4 100];
            app.Label_2 = uilabel(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.ChooseroadLabel = uilabel(app.UIFigure);
            app.ChooseroadLabel.Text = 'Choose road:';
            app.ChoosetickrateLabel = uilabel(app.UIFigure);
            app.ChoosetickrateLabel.Text = 'Choose tick rate:';
            app.roadSelection_DropDown = uidropdown(app.UIFigure);
            app.roadSelection_DropDown.Items = {'i-80', 'us-101', 'peachtree', 'lankershim', '-'};
            app.roadSelection_DropDown.ValueChangedFcn = createCallbackFcn(app, @roadSelection_DropDownValueChanged, true);
            app.roadSelection_DropDown.Value = '-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end